classdef Estrada_Katherine_Reconocimiento_Neumotorax_BD < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        NeumoDiagIALabel         matlab.ui.control.Label
        BUSQUEDAPanel            matlab.ui.container.Panel
        InfoBD                   matlab.ui.control.Label
        BuscarDBButton           matlab.ui.control.Button
        InfobuscarBD             matlab.ui.control.EditField
        BuscarporButtonGroup     matlab.ui.container.ButtonGroup
        NombreButton             matlab.ui.control.RadioButton
        CedulaButton             matlab.ui.control.RadioButton
        INFORMACINPACIENTEPanel  matlab.ui.container.Panel
        UITable                  matlab.ui.control.Table
        ARCHIVODEPACIENTESLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        callingapp;
    end
    
    properties (Access = public)
        f; 
        conexion;
        Bnombre;
        Bcedula;
        Bdiagnostico;
        Cadena;
        CampoaBuscar;
    end
    
    methods (Access = public)
        
        function results = DB(app)
                app.conexion= database('ConexionDiagnostico','root','');
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           
            app.conexion= database('ConexionDiagnostico','root',''); 
            ttotal= 'SELECT * FROM Pacientes_diag_neumotorax';
            app.UITable.ColumnFormat={'char','char','char','char','char','char','char'}
            tabla = fetch(app.conexion,ttotal)
            app.f=max(tabla.idPaciente);
            ft=app.f;
            ct=7;
            app.UITable.Data=cell (ft,ct);
            t=sqlread(app.conexion,'Pacientes_diag_neumotorax')
         
                for (ct=1:7)
                    for (ft=1:app.f)
               switch ct
               case 1
               colum="FechaDiagnostico";
               case 2
               colum="CiudadPaciente";
               case 3
               colum="CIPaciente";
               case 4
               colum="NombrePaciente";
               case 5
               colum="EdadPaciente";
               case 6
               colum="SintomasPaciente";
               case 7
               colum="DiagnosticoPaciente";
               end
                        app.UITable.Data(ft,ct)= tabla {ft,colum};
                    end
                end
        end

        % Selection changed function: BuscarporButtonGroup
        function BuscarporButtonGroupSelectionChanged(app, event)
            selectedButton = app.BuscarporButtonGroup.SelectedObject;
            app.Bnombre= app.NombreButton.Value
            app.Bcedula=app.CedulaButton.Value
            %app.Bdiagnostico=app.DiagnosticoButton.Value
        end

        % Button pushed function: BuscarDBButton
        function BuscarDBButtonPushed(app, event)
            app.f;
            if (app.Bnombre==1)
            app.CampoaBuscar= "NombrePaciente"
            cbuscar=4;
            end
            if (app.Bcedula==1)
            app.CampoaBuscar="CIPaciente" 
            cbuscar=3;
            end
            app.f
             k=0;
              for(bf=1:app.f)
                  bf
                  cbuscar
               if((strcmp(app.UITable.Data(bf,cbuscar),app.Cadena))==1)
                k=k+1% # columnas encontrada
                id=bf % ID columnas encontradas
                disp('coincidencia')
                coinc=app.UITable.Data(id,:)
               end
          end
              resulDB1= cellstr(['Riobamba,',coinc{1}])
              resulDB2= cellstr(['Paciente: ', coinc{4}])
              resulDB3= cellstr(['Edad: ', coinc{5}, ' años'])
              resulDB4= cellstr(['Cedula de identidad: ', coinc{3}])
              resulDB5= cellstr(['Sintomatología: ', coinc{6}])
              resulDB6=cellstr(['Diagnóstico: ', coinc{7}])
              set (app.InfoBD, 'Text', [resulDB1; resulDB2;resulDB3; resulDB4;resulDB5; resulDB6])
        end

        % Value changed function: InfobuscarBD
        function InfobuscarBDValueChanged(app, event)
            app.Cadena = cellstr(get(app.InfobuscarBD, 'Value'));
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ARCHIVODEPACIENTESLabel = uilabel(app.UIFigure);
            app.ARCHIVODEPACIENTESLabel.Text = 'ARCHIVO DE PACIENTES';
            app.INFORMACINPACIENTEPanel = uipanel(app.UIFigure);
            app.INFORMACINPACIENTEPanel.Title = 'INFORMACIÓN PACIENTE';
            app.UITable = uitable(app.INFORMACINPACIENTEPanel);
            app.UITable.ColumnName = {'Fecha'; 'Ciudad'; 'Cedula'; 'Nombre'; 'Edad'; 'Sintomatología'; 'Diagnostico'};
            app.UITable.ColumnEditable = [true false false false false false false false];
            app.BUSQUEDAPanel = uipanel(app.UIFigure);
            app.BUSQUEDAPanel.Title = 'BUSQUEDA';
            app.BuscarporButtonGroup = uibuttongroup(app.BUSQUEDAPanel);
            app.BuscarporButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @BuscarporButtonGroupSelectionChanged, true);
            app.BuscarporButtonGroup.Title = 'Buscar por:';
            app.CedulaButton = uiradiobutton(app.BuscarporButtonGroup);
            app.CedulaButton.Text = 'Cedula';
            app.CedulaButton.Value = true;
            app.NombreButton = uiradiobutton(app.BuscarporButtonGroup);
            app.NombreButton.Text = 'Nombre';
            app.InfobuscarBD = uieditfield(app.BUSQUEDAPanel, 'text');
            app.InfobuscarBD.ValueChangedFcn = createCallbackFcn(app, @InfobuscarBDValueChanged, true);
            app.BuscarDBButton = uibutton(app.BUSQUEDAPanel, 'push');
            app.BuscarDBButton.ButtonPushedFcn = createCallbackFcn(app, @BuscarDBButtonPushed, true);
            app.BuscarDBButton.Text = 'BUSCAR';
            app.InfoBD = uilabel(app.BUSQUEDAPanel);
            app.InfoBD.Text = '';
            app.NeumoDiagIALabel = uilabel(app.UIFigure);
            app.NeumoDiagIALabel.Text = 'NeumoDiag IA';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Estrada_Katherine_Reconocimiento_Neumotorax_BD

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end