classdef Estrada_Katherine_Reconocimiento_Neumotorax_Detalles < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        NeumoDiagIALabel            matlab.ui.control.Label
        DATOSINFORMATIVOSDELPACIENTEPanel  matlab.ui.container.Panel
        DIAGPN                      matlab.ui.control.Label
        DIAGNSTICOLabel             matlab.ui.control.Label
        SINT                        matlab.ui.control.TextArea
        SintomatologaPresenteLabel  matlab.ui.control.Label
        EdLabel                     matlab.ui.control.Label
        CILabel                     matlab.ui.control.Label
        COLabel                     matlab.ui.control.Label
        I4Label                     matlab.ui.control.Label
        I3Label                     matlab.ui.control.Label
        I2Label                     matlab.ui.control.Label
        I1Label                     matlab.ui.control.Label
        Label                       matlab.ui.control.Label
        lyf                         matlab.ui.control.Label
        ANALISISDENEUMOTRAXLabel    matlab.ui.control.Label
    end

    
    properties (Access = private)
        callingapp; % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Estrada_Katherine_Reconocimiento_Neumotorax_app, nombre, edad, cedula, sintomatologia, ciudad, s1, s2, s3, s4, s5, s6, fecha, I1, I2, I3, I4, I5, I6, resultado)
            app.callingapp= Estrada_Katherine_Reconocimiento_Neumotorax_app;
            app.lyf.Text= strcat('Riobamba, ', " ", string(datestr(now)));
            app.Label.Text= nombre;
            app.EdLabel.Text= strcat(string(edad), " ", "años");
            app.CILabel.Text= cedula;
            app.COLabel.Text= ciudad;
            I1
            st1=strlength(string(I1))
            I2
            st2=strlength(string(I2))
            I3
            st3=strlength(string(I3))
            I4
            st4=strlength(string(I4))
            I5
            st5=strlength(string(I5))
            I6
            st6=strlength(string(I6))

            i=1;
            if (st1>0)
             app.SINT.Value{i}=I1;
             i=i+1;
            else 
              I1='';  
            end
            if (st2>0)
             app.SINT.Value{i}=I2;
             i=i+1;
             else 
              I2='';  
            end
            if (st3>0)
             app.SINT.Value{i}=I3;
             i=i+1;
             else 
              I3='';  
            end
            if (st4>0)
             app.SINT.Value{i}=I4;
             i=i+1;
             else 
              I4='';  
            end
            if (st5>0)
             app.SINT.Value{i}=I5;
             i=i+1;
             else 
              I5='';  
            end
              if (st6>0)
             app.SINT.Value{i}=sintomatologia;
             else 
              I6='';  
            end
          app.DIAGPN.Text= resultado;
          for (j=1:i-1)
              j
              k(j)=app.SINT.Value(j)
          end
          h=strcat(I1," ", I2," ",I3," ", I4," ", I5," ", I6)
          conexion= database('ConexionDiagnostico','root','');
          datosDB = table(string(fecha),string(ciudad),string(cedula),string(nombre),strcat(string(edad), " ", "años"), string(h), string(resultado),'VariableNames',{'FechaDiagnostico', 'CiudadPaciente', 'CIPaciente', 'NombrePaciente', 'EdadPaciente', 'SintomasPaciente', 'DiagnosticoPaciente'});
          sqlwrite(conexion,'Pacientes_diag_neumotorax',datosDB);

        end

        % Callback function
        function IMAGENButtonPushed(app, event)
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.callingapp.DIAGNOSTICOButton.Enable = "on" ;         
            delete(app);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ANALISISDENEUMOTRAXLabel = uilabel(app.UIFigure);
            app.ANALISISDENEUMOTRAXLabel.Text = 'ANALISIS DE NEUMOTÓRAX';
            app.lyf = uilabel(app.UIFigure);
            app.lyf.Text = '';
            app.DATOSINFORMATIVOSDELPACIENTEPanel = uipanel(app.UIFigure);
            app.DATOSINFORMATIVOSDELPACIENTEPanel.Title = 'DATOS INFORMATIVOS DEL PACIENTE:';
            app.Label = uilabel(app.DATOSINFORMATIVOSDELPACIENTEPanel);
            app.Label.Text = '';
            app.I1Label = uilabel(app.DATOSINFORMATIVOSDELPACIENTEPanel);
            app.I1Label.Text = 'Paciente:';
            app.I2Label = uilabel(app.DATOSINFORMATIVOSDELPACIENTEPanel);
            app.I2Label.Text = 'Edad:';
            app.I3Label = uilabel(app.DATOSINFORMATIVOSDELPACIENTEPanel);
            app.I3Label.Text = 'Lugar de Origen:';
            app.I4Label = uilabel(app.DATOSINFORMATIVOSDELPACIENTEPanel);
            app.I4Label.Text = 'Cedula de Identidad:';
            app.COLabel = uilabel(app.DATOSINFORMATIVOSDELPACIENTEPanel);
            app.COLabel.Text = '';
            app.CILabel = uilabel(app.DATOSINFORMATIVOSDELPACIENTEPanel);
            app.CILabel.Text = '';
            app.EdLabel = uilabel(app.DATOSINFORMATIVOSDELPACIENTEPanel);
            app.EdLabel.Text = '';
            app.SintomatologaPresenteLabel = uilabel(app.DATOSINFORMATIVOSDELPACIENTEPanel);
            app.SintomatologaPresenteLabel.Text = 'Sintomatología Presente: ';
            app.SINT = uitextarea(app.DATOSINFORMATIVOSDELPACIENTEPanel);
            app.SINT.Editable = 'off';
            app.DIAGNSTICOLabel = uilabel(app.DATOSINFORMATIVOSDELPACIENTEPanel);
            app.DIAGNSTICOLabel.Text = 'DIAGNÓSTICO';
            app.DIAGPN = uilabel(app.DATOSINFORMATIVOSDELPACIENTEPanel);
            app.DIAGPN.Text = '';
            app.NeumoDiagIALabel = uilabel(app.UIFigure);
            app.NeumoDiagIALabel.Text = 'NeumoDiag IA';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Estrada_Katherine_Reconocimiento_Neumotorax_Detalles(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end