classdef Estrada_Katherine_Reconocimiento_Neumotorax_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        InteligenciaArtificialaplicadaalaSaludLabel  matlab.ui.control.Label
        PACIENTESButton             matlab.ui.control.Button
        ImagenRXdeTorxPanel         matlab.ui.container.Panel
        DIAGNOSTICOButton           matlab.ui.control.Button
        VERIMAGENButton             matlab.ui.control.Button
        CARGARIMAGENButton          matlab.ui.control.Button
        UIAxes                      matlab.ui.control.UIAxes
        SintomatologaPanel          matlab.ui.container.Panel
        EspecifiqueLabel            matlab.ui.control.Label
        SintomatologaEditField      matlab.ui.control.EditField
        OtroCheckBox                matlab.ui.control.CheckBox
        SncopeCheckBox              matlab.ui.control.CheckBox
        HemoptisisCheckBox          matlab.ui.control.CheckBox
        TosSecaCheckBox             matlab.ui.control.CheckBox
        DisneaCheckBox              matlab.ui.control.CheckBox
        DolorTorcicoCheckBox        matlab.ui.control.CheckBox
        CIUDADEditField             matlab.ui.control.EditField
        CIUDADORIGENLabel           matlab.ui.control.Label
        EdadAosEditField            matlab.ui.control.EditField
        EdadAosEditFieldLabel       matlab.ui.control.Label
        CeduladeidentidadEditField  matlab.ui.control.EditField
        CeduladeidentidadEditFieldLabel  matlab.ui.control.Label
        NombreEditField             matlab.ui.control.EditField
        NombreEditFieldLabel        matlab.ui.control.Label
        NUEVOPACIENTEButton         matlab.ui.control.Button
        DATOSDELPACIENTELabel       matlab.ui.control.Label
        NeumoDiagIALabel            matlab.ui.control.Label
    end

      
    properties (Access = private)
        Estrada_Katherine_Reconocimiento_Neumotorax_Detalles % DIAGNOSTICO
        Estrada_Katherine_Reconocimiento_Neumotorax_BD % BASE DE DATOS
        net
        trainedNetwork_1
    end
    
    properties (Access = public) 
        imagenRX
        fullpathname
        fecha
        I1
        I2
        I3
        I4
        I5
        I6
        resultado
    end
    
    methods (Access = public)
        
        function imagen(app)
            imshow('App\VIU2.png','Parent',app.UIAxes)
            app.VERIMAGENButton.Enable = "off" ; %% Deshabilitar boton hasta que se cargue la imagen
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, net)

            imagen(app);
            app.net=load('C:\Users\GABRIELA ESTRADA\Documents\MATLAB\Estrada_Katherine_TFM\Resnet18\red.mat')
            app.net=app.net.trainedNetwork_1
    
        end

        % Button pushed function: CARGARIMAGENButton
        function CARGARIMAGENButtonPushed(app, event)
            [filename, pathname]= uigetfile({'*.png'},'File Selector'); %% CARGAR IMAGEN FORMATO PNG
            fullpathname=strcat(pathname, filename);
            [app.imagenRX, map]= imread(fullpathname);
%%%%%%%%%%
        size(app.imagenRX)
        map
        if (size(app.imagenRX,1)~=224 ||size(app.imagenRX,2)~=224 ) 
        app.imagenRX=imresize(app.imagenRX,[224 224]); % Cambio de dimensiones
            if(isempty(map))
            if (size(app.imagenRX,3)==1) % posición 3 del array = 1 (1 solo canal)
                app.imagenRX=cat(3, app.imagenRX, app.imagenRX,app.imagenRX); % CAT Concatena arrays
                size(app.imagenRX)
            end
        else 
            app.imagenRX=ind2rgb(app.imagenRX,map);
            disp('listo')
            end
        else
           disp('img cumple medidas') 
            if(isempty(map))
            if (size(app.imagenRX,3)==1) % posición 3 del array = 1 (1 solo canal)
                app.imagenRX=cat(3, app.imagenRX,app.imagenRX,app.imagenRX); % CAT Concatena arrays
                size(app.imagenRX)
            end
        else 
            app.imagenRX=ind2rgb(app.imagenRX,map);
            disp('listo')
            end
         end

%%%%%%%%%%%%%%%
            
            imshow(app.imagenRX,'Parent',app.UIAxes);
            YPred = classify(app.net,app.imagenRX)
            app.resultado= string(YPred)
            if (app.resultado==('1 Neumotorax'))
                app.resultado= 'POSITIVO'
            else
                app.resultado= 'NEGATIVO'
            end
            disp(fullpathname);
            app.VERIMAGENButton.Enable = "on" ; %% Habilitar boton

        end

        % Button pushed function: DIAGNOSTICOButton
        function DIAGNOSTICOButtonPushed(app, event)
           app.fecha=datestr(now);
           im=isempty(app.imagenRX);
            ciudad = app.CIUDADEditField.Value;
            cd=strlength(ciudad);
            nombre = app.NombreEditField.Value;
            n=strlength(nombre);
            cedula = string(app.CeduladeidentidadEditField.Value);
            c=strlength(cedula);
            edad = string(app.EdadAosEditField.Value);
            e=strlength(edad);
            s1= app.DolorTorcicoCheckBox.Value
            app.I1
            s2 = app.DisneaCheckBox.Value
            app.I2
            s3= app.TosSecaCheckBox.Value
            app.I3
            s4= app.HemoptisisCheckBox.Value
            app.I4
            s5 = app.SncopeCheckBox.Value
            app.I5
            s6 = app.OtroCheckBox.Value
            sintomatologia = app.SintomatologaEditField.Value;
            stmg=strlength(sintomatologia)
            if((s1==true)|| (s2==true)||(s3==true)|| (s4==true) || (s5==true)|| (s6==true))
                if (s6==true)
                 if (stmg~=0)
                  sm=1
                  disp('campos llenos')
                else
                  sm=0
                  disp('sintomas por llenar')
                  end
                else 

                    disp('campos llenos')
                  sm=1;
                end
            else 
                sm=0;  
                disp('sintomas por elegir')
            end

            if ((cd ~= 0) & (n ~= 0) & (e~=0) & (c~=0) & (im == 0) & (sm~=0))
            app.DIAGNOSTICOButton.Enable = "off" ; %% Deshabilitar boton una vez pulsado
            app.VERIMAGENButton.Enable = "on" ; %% Habilitar boton una vez pulsado
            app.Estrada_Katherine_Reconocimiento_Neumotorax_Detalles;
            app.Estrada_Katherine_Reconocimiento_Neumotorax_Detalles=Estrada_Katherine_Reconocimiento_Neumotorax_Detalles(app,app.NombreEditField.Value, app.EdadAosEditField.Value,app.CeduladeidentidadEditField.Value, app.SintomatologaEditField.Value,app.CIUDADEditField.Value, app.OtroCheckBox.Value, app.SncopeCheckBox.Value,app.HemoptisisCheckBox.Value, app.TosSecaCheckBox.Value, app.DisneaCheckBox.Value, app.DolorTorcicoCheckBox.Value,app.fecha, app.I1,app.I2, app.I3, app.I4, app.I5, app.I6, app.resultado);
            else
               if ((cd == 0) ||(n == 0) || (e == 0) || (c==0) || (sm==0) )
                warndlg( 'Porfavor llenar todos los campos solicitados' , 'Advertencia')
                app.DIAGNOSTICOButton.Enable = "on" ; %% Habilitar boton una vez pulsado
               else
                   if (im == 1)
                warndlg( 'Porfavor cargar la imagen para el analisis' , 'Advertencia' )
                app.VERIMAGENButton.Enable = "off" ; %% Deshabilitar boton hasta que se cargue la imagen
                app.DIAGNOSTICOButton.Enable = "on" ; %% Habilitar boton una vez pulsado    
                   end
               end
            end
        end

        % Button pushed function: NUEVOPACIENTEButton
        function NUEVOPACIENTEButtonPushed(app, event)
            app.CIUDADEditField.Value='';
            app.NombreEditField.Value= '';
            app.EdadAosEditField.Value='';
            app.CeduladeidentidadEditField.Value= '';
            app.SintomatologaEditField.Value='';
            app.DolorTorcicoCheckBox.Value=false;
            app.DisneaCheckBox.Value=false;
            app.TosSecaCheckBox.Value=false;
            app.HemoptisisCheckBox.Value=false;
            app.SncopeCheckBox.Value=false;
            app.OtroCheckBox.Value=false;
            app.imagenRX=[];
            cla(app.UIAxes, 'reset')
            imshow('App\VIU2.png','Parent',app.UIAxes);
            app.DIAGNOSTICOButton.Enable = "on" ; %% Habilitar boton una vez pulsado
            delete(app.Estrada_Katherine_Reconocimiento_Neumotorax_Detalles);
         
        end

        % Value changed function: NombreEditField
        function NombreEditFieldValueChanged(app, event)
            nombre = app.NombreEditField.Value;
        end

        % Value changed function: CeduladeidentidadEditField
        function CeduladeidentidadEditFieldValueChanged(app, event)
            cedula = string(app.CeduladeidentidadEditField.Value);
        end

        % Value changed function: EdadAosEditField
        function EdadAosEditFieldValueChanged(app, event)
            edad = string(app.EdadAosEditField.Value);       
        end

        % Value changed function: SintomatologaEditField
        function SintomatologaEditFieldValueChanged(app, event)
            sintomatologia = app.SintomatologaEditField.Value;
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            
        end

        % Button pushed function: VERIMAGENButton
        function VERIMAGENButtonPushed(app, event)
            disp('aquidetalles main')
            disp(app.fullpathname)
            figure('Name',app.NombreEditField.Value)
            imshow(app.imagenRX)
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app.Estrada_Katherine_Reconocimiento_Neumotorax_Detalles)
            delete(app)
        end

        % Value changed function: CIUDADEditField
        function CIUDADEditFieldValueChanged(app, event)
            ciudad= app.CIUDADEditField.Value;
            
        end

        % Value changed function: DolorTorcicoCheckBox
        function DolorTorcicoCheckBoxValueChanged(app, event)
            s1= app.DolorTorcicoCheckBox.Value;
            app.I1='Dolor Torácico';

        end

        % Value changed function: DisneaCheckBox
        function DisneaCheckBoxValueChanged(app, event)
            s2 = app.DisneaCheckBox.Value;
            app.I2='Disnea';

            
        end

        % Value changed function: TosSecaCheckBox
        function TosSecaCheckBoxValueChanged(app, event)
            s3= app.TosSecaCheckBox.Value;      
            app.I3='Tos Seca';
            
        end

        % Value changed function: HemoptisisCheckBox
        function HemoptisisCheckBoxValueChanged(app, event)
            s4= app.HemoptisisCheckBox.Value;
            app.I4='Hemoptisis';
             
        end

        % Value changed function: SncopeCheckBox
        function SncopeCheckBoxValueChanged(app, event)
            s5 = app.SncopeCheckBox.Value
            app.I5='Síncope'
        end

        % Value changed function: OtroCheckBox
        function OtroCheckBoxValueChanged(app, event)
            s6 = app.OtroCheckBox.Value;
            app.I6='Otros'
            
        end

        % Callback function
        function FechaDatePickerValueChanged(app, event)
            %fecha= string(app.FechaDatePicker.Value)
            
        end

        % Button pushed function: PACIENTESButton
        function PACIENTESButtonPushed(app, event)
           Estrada_Katherine_Reconocimiento_Neumotorax_BD;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.NeumoDiagIALabel = uilabel(app.UIFigure);
            app.NeumoDiagIALabel.Text = 'NeumoDiag IA';
            app.DATOSDELPACIENTELabel = uilabel(app.UIFigure);
            app.DATOSDELPACIENTELabel.Text = 'DATOS DEL PACIENTE';
            app.NUEVOPACIENTEButton = uibutton(app.UIFigure, 'push');
            app.NUEVOPACIENTEButton.ButtonPushedFcn = createCallbackFcn(app, @NUEVOPACIENTEButtonPushed, true);
            app.NUEVOPACIENTEButton.Text = 'NUEVO PACIENTE';
            app.NombreEditFieldLabel = uilabel(app.UIFigure);
            app.NombreEditFieldLabel.Text = 'Nombre';
            app.NombreEditField = uieditfield(app.UIFigure, 'text');
            app.NombreEditField.ValueChangedFcn = createCallbackFcn(app, @NombreEditFieldValueChanged, true);
            app.NombreEditField.Placeholder = 'Campo Obligatorio ';
            app.CeduladeidentidadEditFieldLabel = uilabel(app.UIFigure);
            app.CeduladeidentidadEditFieldLabel.Text = 'Cedula de identidad';
            app.CeduladeidentidadEditField = uieditfield(app.UIFigure, 'text');
            app.CeduladeidentidadEditField.InputType = 'digits';
            app.CeduladeidentidadEditField.ValueChangedFcn = createCallbackFcn(app, @CeduladeidentidadEditFieldValueChanged, true);
            app.CeduladeidentidadEditField.Placeholder = 'Campo Obligatorio';
            app.EdadAosEditFieldLabel = uilabel(app.UIFigure);
            app.EdadAosEditFieldLabel.Text = 'Edad (Años)';
            app.EdadAosEditField = uieditfield(app.UIFigure, 'text');
            app.EdadAosEditField.InputType = 'digits';
            app.EdadAosEditField.ValueChangedFcn = createCallbackFcn(app, @EdadAosEditFieldValueChanged, true);
            app.EdadAosEditField.Placeholder = 'Campo Obligatorio ';
            app.CIUDADORIGENLabel = uilabel(app.UIFigure);
            app.CIUDADORIGENLabel.Text = 'Ciudad de Origen';
            app.CIUDADEditField = uieditfield(app.UIFigure, 'text');
            app.CIUDADEditField.ValueChangedFcn = createCallbackFcn(app, @CIUDADEditFieldValueChanged, true);
            app.CIUDADEditField.Placeholder = 'Campo Obligatorio ';
            app.SintomatologaPanel = uipanel(app.UIFigure);
            app.SintomatologaPanel.Title = 'Sintomatología';
            app.DolorTorcicoCheckBox = uicheckbox(app.SintomatologaPanel);
            app.DolorTorcicoCheckBox.ValueChangedFcn = createCallbackFcn(app, @DolorTorcicoCheckBoxValueChanged, true);
            app.DolorTorcicoCheckBox.Text = 'Dolor Torácico';
            app.DisneaCheckBox = uicheckbox(app.SintomatologaPanel);
            app.DisneaCheckBox.ValueChangedFcn = createCallbackFcn(app, @DisneaCheckBoxValueChanged, true);
            app.DisneaCheckBox.Text = 'Disnea';
            app.TosSecaCheckBox = uicheckbox(app.SintomatologaPanel);
            app.TosSecaCheckBox.ValueChangedFcn = createCallbackFcn(app, @TosSecaCheckBoxValueChanged, true);
            app.TosSecaCheckBox.Text = 'Tos Seca';
            app.HemoptisisCheckBox = uicheckbox(app.SintomatologaPanel);
            app.HemoptisisCheckBox.ValueChangedFcn = createCallbackFcn(app, @HemoptisisCheckBoxValueChanged, true);
            app.HemoptisisCheckBox.Text = 'Hemoptisis';
            app.SncopeCheckBox = uicheckbox(app.SintomatologaPanel);
            app.SncopeCheckBox.ValueChangedFcn = createCallbackFcn(app, @SncopeCheckBoxValueChanged, true);
            app.SncopeCheckBox.Text = 'Síncope';
            app.OtroCheckBox = uicheckbox(app.SintomatologaPanel);
            app.OtroCheckBox.ValueChangedFcn = createCallbackFcn(app, @OtroCheckBoxValueChanged, true);
            app.OtroCheckBox.Text = 'Otro';
            app.SintomatologaEditField = uieditfield(app.SintomatologaPanel, 'text');
            app.SintomatologaEditField.ValueChangedFcn = createCallbackFcn(app, @SintomatologaEditFieldValueChanged, true);
            app.SintomatologaEditField.Placeholder = 'Otro';
            app.EspecifiqueLabel = uilabel(app.SintomatologaPanel);
            app.EspecifiqueLabel.Text = 'Especifique:';
            app.ImagenRXdeTorxPanel = uipanel(app.UIFigure);
            app.ImagenRXdeTorxPanel.Title = 'Imagen RX de Toráx';
            app.UIAxes = uiaxes(app.ImagenRXdeTorxPanel);
            app.UIAxes.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.UIAxes.YTickLabel = '';
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.CARGARIMAGENButton = uibutton(app.ImagenRXdeTorxPanel, 'push');
            app.CARGARIMAGENButton.ButtonPushedFcn = createCallbackFcn(app, @CARGARIMAGENButtonPushed, true);
            app.CARGARIMAGENButton.Text = 'CARGAR IMAGEN';
            app.VERIMAGENButton = uibutton(app.ImagenRXdeTorxPanel, 'push');
            app.VERIMAGENButton.ButtonPushedFcn = createCallbackFcn(app, @VERIMAGENButtonPushed, true);
            app.VERIMAGENButton.Text = 'VER IMAGEN';
            app.DIAGNOSTICOButton = uibutton(app.ImagenRXdeTorxPanel, 'push');
            app.DIAGNOSTICOButton.ButtonPushedFcn = createCallbackFcn(app, @DIAGNOSTICOButtonPushed, true);
            app.DIAGNOSTICOButton.Text = 'DIAGNOSTICO';
            app.PACIENTESButton = uibutton(app.UIFigure, 'push');
            app.PACIENTESButton.ButtonPushedFcn = createCallbackFcn(app, @PACIENTESButtonPushed, true);
            app.PACIENTESButton.Text = 'PACIENTES';
            app.InteligenciaArtificialaplicadaalaSaludLabel = uilabel(app.UIFigure);
            app.InteligenciaArtificialaplicadaalaSaludLabel.Text = 'Inteligencia Artificial aplicada a la Salud';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Estrada_Katherine_Reconocimiento_Neumotorax_app(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end